classdef mri_tumor_detect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BrainTumorDetectionLabel  matlab.ui.control.Label
        DetectTumorButton         matlab.ui.control.Button
        LoadImageButton           matlab.ui.control.Button
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        UploadedImage % Variable to store the image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file, path] = uigetfile('*.jpg;*.png', 'Select an Image');
            if isequal(file,0)
                return;
            end
            app.UploadedImage = imread(fullfile(path, file));
            % Display the image in UIAxes
            imshow(app.UploadedImage, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Uploaded Image');
        end

        % Button pushed function: DetectTumorButton
        function DetectTumorButtonPushed(app, event)
            if isempty(app.UploadedImage)
                uialert(app, 'Please upload an image first.', 'Error');
                return;
            end

            img = app.UploadedImage;

            % Convert the image to binary using a threshold
            bw = im2bw(img, 0.7);

            % Label connected components in the binary image
            label = bwlabel(bw);

            % Compute properties of image regions
            stats = regionprops(label, 'Solidity', 'Area');

            % Extract the solidity and area of each region
            density = [stats.Solidity];
            area = [stats.Area];

            % Find regions with high density
            high_dense_area = density > 0.5;

            % Find the region with the maximum area among high-density regions
            max_area = max(area(high_dense_area));
            tumor_label = find(area == max_area);

            % Create a binary image with the tumor
            tumor = ismember(label, tumor_label);

            % Dilate the tumor region
            se = strel('square', 5);
            tumor = imdilate(tumor, se);

            % Display the tumor image in UIAxes_2
            imshow(tumor, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Detected Tumor');

            % Detect boundaries of the tumor
            [B, ~] = bwboundaries(tumor, 'noholes');

            % Show the original image with the tumor boundaries overlaid
            imshow(img, 'Parent', app.UIAxes_2); % You may want to use another axes for boundaries
            hold(app.UIAxes_2, 'on');
            for i = 1:length(B)
                plot(app.UIAxes_2, B{i}(:,2), B{i}(:,1), 'y', 'linewidth', 1.45);
            end
            title(app.UIAxes_2, 'Detected Tumor Boundaries');
            hold(app.UIAxes_2, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DetectTumorButton = uibutton(app.UIFigure, 'push');
            app.DetectTumorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectTumorButtonPushed, true);
            app.DetectTumorButton.Text = 'Detect Tumor';
            app.BrainTumorDetectionLabel = uilabel(app.UIFigure);
            app.BrainTumorDetectionLabel.Text = 'Brain Tumor Detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mri_tumor_detect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end